classdef CustomerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        ShowerGel    matlab.ui.container.Menu
        Shampoo      matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        item1        matlab.ui.control.Button
        item2        matlab.ui.control.Button
        item1_plus   matlab.ui.control.Button
        item2_plus   matlab.ui.control.Button
        item1_minus  matlab.ui.control.Button
        item2_minus  matlab.ui.control.Button
        CenterPanel  matlab.ui.container.Panel
        Image        matlab.ui.control.Image
        Label        matlab.ui.control.Label
        unitPrice    matlab.ui.control.NumericEditField
        PaySuccess   matlab.ui.control.Button
        RightPanel   matlab.ui.container.Panel
        UITable      matlab.ui.control.Table
        Label_2      matlab.ui.control.Label
        TotalPrice   matlab.ui.control.NumericEditField
        PayButton    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        item_nums = 1;
        item_name = [""];
        item_number = [];
        item_price = [];
        
        db = Database();
    end
    
    methods (Access = private)
        
        function result = change_order_add(app, item)
            % find goods' price 
            goods = app.db.findGoods(item.Text);
            if goods.brand == item.Text
                app.unitPrice.Value = goods.price;
            end
            
            
            is_find = false;
            %app.UITable.RowName = {'1';};
            % check if item is in the order list
            for i = 1:app.item_nums-1
                % find the same item in the order, increase number and
                % price
                if app.item_name(i,1) == item.Text
                    app.item_number(i,1) = app.item_number(i) + 1;
                    
                    app.item_price(i,1) = app.item_price(i) + app.unitPrice.Value;
                    is_find = true;
                end
            end
            
            if is_find == false
                %app.UITable.RowName{app.item_nums} = app.item_nums;
                if app.item_nums == 1
                    app.item_name = [""];
                end
                app.item_name(app.item_nums,1) = item.Text;
                app.item_number(app.item_nums,1) = 1;
                app.item_price(app.item_nums,1) = app.unitPrice.Value;
                app.item_nums = app.item_nums + 1;
            end
            % change total price
            total_price = sum(app.item_price);
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
        end
        
        
        function results = change_order_sub(app,item)
            is_find = false;
            %app.UITable.RowName = {'1';};
            % check if item is in the order list
            
            for i = 1:app.item_nums-1
                % find the same item in the order, increase number and
                % price
                if app.item_name(i,1) == item.Text
                    app.item_number(i,1) = app.item_number(i) - 1;
                    app.item_price(i,1) = app.item_price(i) - app.unitPrice.Value;
                    is_find = true;
                    break;
                end
            end
            if is_find == true && app.item_number(i,1) == 0
                
                %app.UITable.RowName{i,:} = [];
                app.item_name(i,:) = [];
                app.item_number(i,:) = [];
                app.item_price(i,:) = [];
                app.item_nums = app.item_nums - 1;
            end
            % change total price
            total_price = sum(app.item_price);
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x', 260};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: ShowerGel
        function ShowerGelSelected(app, event)
            % change the button Text
            app.item1.Text = "多芬";
            app.item2.Text = "强生";
            
            % show the button UI
            app.item1.Visible = 'on';
            app.item2.Visible = 'on';
            app.item1_plus.Visible = 'on';
            app.item2_plus.Visible = 'on';
            app.item1_minus.Visible = 'on';
            app.item2_minus.Visible = 'on';
        end

        % Menu selected function: Shampoo
        function ShampooSelected(app, event)
            % change the button Text
            app.item1.Text = "海飞丝";
            app.item2.Text = "飘柔";
            
            % show the button UI
            app.item1.Visible = 'on';
            app.item2.Visible = 'on';
            app.item1_plus.Visible = 'on';
            app.item2_plus.Visible = 'on';
            app.item1_minus.Visible = 'on';
            app.item2_minus.Visible = 'on';
        end

        % Button pushed function: item1
        function item1ButtonPushed(app, event)
            if app.item1.Text == "多芬"
                app.Image.ImageSource = 'img\美妆洗护\沐浴露\dove.jpg';
                app.unitPrice.Value = 13;
                
            elseif app.item1.Text == "海飞丝"
                app.Image.ImageSource = 'img\美妆洗护\洗发露\hfs.jpg';
                app.unitPrice.Value = 18;
            end
            % value = goods.price
            
            app.Label.Visible = 'on';
            app.unitPrice.Visible = 'on';
            app.Image.Visible = 'on';
        end

        % Button pushed function: item2
        function item2ButtonPushed(app, event)
            if app.item2.Text == "强生"
                app.Image.ImageSource = 'img\美妆洗护\沐浴露\johnson.jpg';
                app.unitPrice.Value = 20;
            elseif app.item2.Text == "飘柔"
                app.Image.ImageSource = 'img\美妆洗护\洗发露\rejoice.jpg';
                app.unitPrice.Value = 15;
            end
            
            app.Label.Visible = 'on';
            app.unitPrice.Visible = 'on';
            app.Image.Visible = 'on';
        end

        % Button pushed function: item1_plus
        function item1_plusButtonPushed(app, event)
            app.change_order_add(app.item1);
            
            
        end

        % Button pushed function: item2_plus
        function item2_plusButtonPushed(app, event)
            app.change_order_add(app.item2);
        end

        % Button pushed function: item1_minus
        function item1_minusButtonPushed(app, event)
            app.change_order_sub(app.item1);
        end

        % Button pushed function: item2_minus
        function item2_minusButtonPushed(app, event)
            app.change_order_sub(app.item2);
        end

        % Button pushed function: PayButton
        function PayButtonPushed(app, event)
            % create order and write it into database once we finish paying
            % all goods are in database, what we have to do is to find the
            % goods obj in the database
            order = Order(app.db.db_orders_count);
            
            for i = 1:app.item_nums-1
                name = app.item_name(i, 1);
                num = app.item_number(i, 1);
                goods = app.db.findGoods(name);
                if goods.brand == name
                    for j = 1:num
                        order.add_item_and_update_price(goods);
                    end
                end
            end
            % order ID start from 0
            app.db.writeOrder(order);
            
        
            app.item_name = [];
            app.item_number = [];
            app.item_price = [];
            app.item_nums = 1;
            
            % change total price
            total_price = 0;
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
            app.PaySuccess.Visible = 'on';
            
            
        end

        % Button pushed function: PaySuccess
        function PaySuccessButtonPushed(app, event)
            app.PaySuccess.Visible = 'off';
            
            
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            %add path once we select the menu
            addpath('src\');
            addpath('img\');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '美妆洗护';
            app.ShowerGel = uimenu(app.Menu);
            app.ShowerGel.MenuSelectedFcn = createCallbackFcn(app, @ShowerGelSelected, true);
            app.ShowerGel.Text = '沐浴露';
            app.Shampoo = uimenu(app.Menu);
            app.Shampoo.MenuSelectedFcn = createCallbackFcn(app, @ShampooSelected, true);
            app.Shampoo.Text = '洗发露';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '食品';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.item1 = uibutton(app.LeftPanel, 'push');
            app.item1.ButtonPushedFcn = createCallbackFcn(app, @item1ButtonPushed, true);
            app.item1.Visible = 'off';
            app.item1.Text = '';
            app.item2 = uibutton(app.LeftPanel, 'push');
            app.item2.ButtonPushedFcn = createCallbackFcn(app, @item2ButtonPushed, true);
            app.item2.Visible = 'off';
            app.item2.Text = '';
            app.item1_plus = uibutton(app.LeftPanel, 'push');
            app.item1_plus.ButtonPushedFcn = createCallbackFcn(app, @item1_plusButtonPushed, true);
            app.item1_plus.Visible = 'off';
            app.item1_plus.Text = {'+'; ''};
            app.item2_plus = uibutton(app.LeftPanel, 'push');
            app.item2_plus.ButtonPushedFcn = createCallbackFcn(app, @item2_plusButtonPushed, true);
            app.item2_plus.Visible = 'off';
            app.item2_plus.Text = {'+'; ''};
            app.item1_minus = uibutton(app.LeftPanel, 'push');
            app.item1_minus.ButtonPushedFcn = createCallbackFcn(app, @item1_minusButtonPushed, true);
            app.item1_minus.Visible = 'off';
            app.item1_minus.Text = '-';
            app.item2_minus = uibutton(app.LeftPanel, 'push');
            app.item2_minus.ButtonPushedFcn = createCallbackFcn(app, @item2_minusButtonPushed, true);
            app.item2_minus.Visible = 'off';
            app.item2_minus.Text = '-';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.Visible = 'off';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '单价：';
            app.unitPrice = uieditfield(app.CenterPanel, 'numeric');
            app.unitPrice.Limits = [0 Inf];
            app.unitPrice.Editable = 'off';
            app.unitPrice.Visible = 'off';
            app.unitPrice.Value = 1;
            app.PaySuccess = uibutton(app.CenterPanel, 'push');
            app.PaySuccess.ButtonPushedFcn = createCallbackFcn(app, @PaySuccessButtonPushed, true);
            app.PaySuccess.Visible = 'off';
            app.PaySuccess.Text = '支付成功！点击继续下次购物';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'商品'; '数量'; '价钱'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '总价：';
            app.TotalPrice = uieditfield(app.RightPanel, 'numeric');
            app.TotalPrice.Limits = [0 Inf];
            app.TotalPrice.Editable = 'off';
            app.PayButton = uibutton(app.RightPanel, 'push');
            app.PayButton.ButtonPushedFcn = createCallbackFcn(app, @PayButtonPushed, true);
            app.PayButton.Text = '支付';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end